classdef FINAL_2_Beta_GUI_7_6_21_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        mASLLabel                       matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        decimalLabel_2                  matlab.ui.control.Label
        decimalLabel                    matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        m3mLabel                        matlab.ui.control.Label
        yrsLabel                        matlab.ui.control.Label
        decimalLabel_3                  matlab.ui.control.Label
        m3Label_2                       matlab.ui.control.Label
        DiscountRateEditField           matlab.ui.control.NumericEditField
        DiscountRateEditFieldLabel      matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        NETPRESENTVALUEEditField        matlab.ui.control.NumericEditField
        NETPRESENTVALUEEditFieldLabel   matlab.ui.control.Label
        CALCULATEButton                 matlab.ui.control.Button
        COSTSLabel                      matlab.ui.control.Label
        BENEFITSLabel                   matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        YearlyBudgetEditField           matlab.ui.control.NumericEditField
        YearlyBudgetEditFieldLabel      matlab.ui.control.Label
        PropertyValueSumEditField       matlab.ui.control.NumericEditField
        PropertyValueSumEditFieldLabel  matlab.ui.control.Label
        MunicipleTaxRateEditField       matlab.ui.control.NumericEditField
        MunicipleTaxRateEditFieldLabel  matlab.ui.control.Label
        RenourishmentEventsEditField    matlab.ui.control.NumericEditField
        RenourishmentEventsEditFieldLabel  matlab.ui.control.Label
        AlongshoreLengthASLEditField    matlab.ui.control.NumericEditField
        AlongshoreLengthASLEditFieldLabel  matlab.ui.control.Label
        VolumeDemandEditField           matlab.ui.control.NumericEditField
        VolumeDemandEditFieldLabel      matlab.ui.control.Label
        ProjectLifetimeEditField        matlab.ui.control.NumericEditField
        ProjectLifetimeEditFieldLabel   matlab.ui.control.Label
        CostShareEditField              matlab.ui.control.NumericEditField
        CostShareEditFieldLabel         matlab.ui.control.Label
        RenourishmentCostsEditField     matlab.ui.control.NumericEditField
        RenourishmentCostsEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            RC=app.RenourishmentCostsEditField.Value;
            CS=app.CostShareEditField.Value;
            PL=app.ProjectLifetimeEditField.Value;
            VD=app.VolumeDemandEditField.Value;
            ASL=app.AlongshoreLengthASLEditField.Value;
            RE=app.RenourishmentEventsEditField.Value;
            MR=app.MunicipleTaxRateEditField.Value;
            PV=app.PropertyValueSumEditField.Value;
            YB=app.YearlyBudgetEditField.Value;
            DR=app.DiscountRateEditField.Value;
            
            T=1:1:PL;
            it=length(T);
            DISC=zeros(1,it);
            DISB=zeros(1,it);
            CD=zeros(1,it);
            BD=zeros(1,it);
                for t=1:it
                 DISC(t)=((1+DR)^t);
                 CD(t)=((RC*((VD*RE*ASL)/PL)*CS)/DISC(t));
                 TC=sum(CD);
                 DISB(t)=((1+DR)^t);
                 BD(t)=((PV*MR)/DISB(t));
                 TB=sum(BD)+YB;
                end
            
            app.NETPRESENTVALUEEditField.Value=(TB-TC)/ASL;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RenourishmentCostsEditField.Value=0;
            app.CostShareEditField.Value=0;
            app.ProjectLifetimeEditField.Value=0;
            app.VolumeDemandEditField.Value=0;
            app.AlongshoreLengthASLEditField.Value=0;
            app.RenourishmentEventsEditField.Value=0;
            app.MunicipleTaxRateEditField.Value=0;
            app.PropertyValueSumEditField.Value=0;
            app.YearlyBudgetEditField.Value=0;
            app.DiscountRateEditField.Value=0;
            app.NETPRESENTVALUEEditField.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RenourishmentCostsEditFieldLabel = uilabel(app.UIFigure);
            app.RenourishmentCostsEditFieldLabel.Text = {'Renourishment'; 'Costs'};
            app.RenourishmentCostsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostShareEditFieldLabel = uilabel(app.UIFigure);
            app.CostShareEditFieldLabel.Text = 'Cost-Share';
            app.CostShareEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectLifetimeEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectLifetimeEditFieldLabel.Text = 'Project Lifetime';
            app.ProjectLifetimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeDemandEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeDemandEditFieldLabel.Text = {'Volume'; 'Demand'};
            app.VolumeDemandEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlongshoreLengthASLEditFieldLabel = uilabel(app.UIFigure);
            app.AlongshoreLengthASLEditFieldLabel.Text = {'Alongshore'; 'Length (ASL)'};
            app.AlongshoreLengthASLEditField = uieditfield(app.UIFigure, 'numeric');
            app.RenourishmentEventsEditFieldLabel = uilabel(app.UIFigure);
            app.RenourishmentEventsEditFieldLabel.Text = {'Renourishment'; 'Events'};
            app.RenourishmentEventsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MunicipleTaxRateEditFieldLabel = uilabel(app.UIFigure);
            app.MunicipleTaxRateEditFieldLabel.Text = {'Municiple'; 'Tax Rate'};
            app.MunicipleTaxRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PropertyValueSumEditFieldLabel = uilabel(app.UIFigure);
            app.PropertyValueSumEditFieldLabel.Text = {'Property'; 'Value Sum'};
            app.PropertyValueSumEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearlyBudgetEditFieldLabel = uilabel(app.UIFigure);
            app.YearlyBudgetEditFieldLabel.Text = 'Yearly Budget';
            app.YearlyBudgetEditField = uieditfield(app.UIFigure, 'numeric');
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.BENEFITSLabel = uilabel(app.UIFigure);
            app.BENEFITSLabel.Text = 'BENEFITS';
            app.COSTSLabel = uilabel(app.UIFigure);
            app.COSTSLabel.Text = 'COSTS';
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.NETPRESENTVALUEEditFieldLabel = uilabel(app.UIFigure);
            app.NETPRESENTVALUEEditFieldLabel.Text = 'NET PRESENT VALUE';
            app.NETPRESENTVALUEEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DiscountRateEditFieldLabel = uilabel(app.UIFigure);
            app.DiscountRateEditFieldLabel.Text = 'Discount Rate';
            app.DiscountRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.m3Label_2 = uilabel(app.UIFigure);
            app.m3Label_2.Text = '$/m^3';
            app.decimalLabel_3 = uilabel(app.UIFigure);
            app.decimalLabel_3.Text = '% (decimal)';
            app.yrsLabel = uilabel(app.UIFigure);
            app.yrsLabel.Text = 'yrs';
            app.m3mLabel = uilabel(app.UIFigure);
            app.m3mLabel.Text = 'm^3/m';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = 'm';
            app.decimalLabel = uilabel(app.UIFigure);
            app.decimalLabel.Text = '% (decimal)';
            app.decimalLabel_2 = uilabel(app.UIFigure);
            app.decimalLabel_2.Text = '% (decimal)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '$';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '$';
            app.mASLLabel = uilabel(app.UIFigure);
            app.mASLLabel.Text = '$/mASL';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'GUI_FlowChart_Geologic&Economic_Separate_6_25_21.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINAL_2_Beta_GUI_7_6_21_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end